classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TyButton            matlab.ui.control.Button
        PrawoButton         matlab.ui.control.Button
        LewoButton          matlab.ui.control.Button
        PrzdButton          matlab.ui.control.Button
        Start_scan          matlab.ui.control.Button
        Start_comunication  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T_ser % Description
        
        slamAlg = lidarSLAM(100, 0.6); % Description
    end


    methods (Access = public)
        
        function results = data_lidar_pro(app,coll_data)
            dists = [];
            angles = [];
            for it = 1:1:(length(coll_data))
                 string_dat = strsplit(string(coll_data(it)), ';');
                 temp_1 = str2double(string_dat(1));
                 temp_1 = (13* (temp_1^(-1)) ) / 100 +0.04;
                 temp_2 = str2double(string_dat(2));
                if (temp_2 >=0 && temp_1 >= 0 )
                    dists =[dists,temp_1] ;
                    angles =[angles, deg2rad(360-temp_2)];
                end
            
            end
            dists;
            angles;
            scan = lidarScan(dists,angles);
            [isScanAccepted, loopClosureInfo, optimizationInfo] = addScan(app.slamAlg,scan);
            show(app.slamAlg);
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, t)
            app.T_ser = tcpclient("192.168.150.253",8888);
        end

        % Button pushed function: Start_scan
        function push_button_scan(app, event)
           writeline(app.T_ser,"run");
        end

        % Button pushed function: Start_comunication
        function Start_program(app, event)
            

while true
   coll_data = [];
   data= string(readline(app.T_ser));
   TF = ~(isempty(data));

   if  TF
    if data == "start"
        data = string(readline(app.T_ser));
        while data ~= "stop"
            coll_data = [coll_data,data],
            data = string(readline(app.T_ser));
        end
        data_lidar_pro(app,coll_data);
    end
   end

end
        end

        % Button pushed function: LewoButton
        function LewoButtonPushed(app, event)
                       writeline(app.T_ser,"left");
        end

        % Button pushed function: PrawoButton
        function PrawoButtonPushed(app, event)
                       writeline(app.T_ser,"right");
        end

        % Button pushed function: PrzdButton
        function PrzdButtonPushed(app, event)
                       writeline(app.T_ser,"forward");
        end

        % Button pushed function: TyButton
        function TyButtonPushed(app, event)
                       writeline(app.T_ser,"backward")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Zmapowany obszar')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Start_comunication = uibutton(app.UIFigure, 'push');
            app.Start_comunication.ButtonPushedFcn = createCallbackFcn(app, @Start_program, true);
            app.Start_comunication.Text = 'Start program';
            app.Start_scan = uibutton(app.UIFigure, 'push');
            app.Start_scan.ButtonPushedFcn = createCallbackFcn(app, @push_button_scan, true);
            app.Start_scan.Text = 'Nowy skan';
            app.PrzdButton = uibutton(app.UIFigure, 'push');
            app.PrzdButton.ButtonPushedFcn = createCallbackFcn(app, @PrzdButtonPushed, true);
            app.PrzdButton.Text = 'Przód';
            app.LewoButton = uibutton(app.UIFigure, 'push');
            app.LewoButton.ButtonPushedFcn = createCallbackFcn(app, @LewoButtonPushed, true);
            app.LewoButton.Text = 'Lewo';
            app.PrawoButton = uibutton(app.UIFigure, 'push');
            app.PrawoButton.ButtonPushedFcn = createCallbackFcn(app, @PrawoButtonPushed, true);
            app.PrawoButton.Text = 'Prawo';
            app.TyButton = uibutton(app.UIFigure, 'push');
            app.TyButton.ButtonPushedFcn = createCallbackFcn(app, @TyButtonPushed, true);
            app.TyButton.Text = 'Tył';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end